classdef stroik_aplikacja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CzstotliwoHzLabel  matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        Gauge              matlab.ui.control.SemicircularGauge
        StrunaeButton      matlab.ui.control.Button
        StrunaBButton      matlab.ui.control.Button
        StrunaGButton      matlab.ui.control.Button
        StrunaDButton      matlab.ui.control.Button
        StrunaAButton      matlab.ui.control.Button
        StrunaEButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StrunaEButton
        function StrunaEButtonPushed(app, event)
            error= -10;
            app.Gauge.Limits = [57.41 107.41];
            app.Gauge.MajorTicks = [57.41 62.41 67.41 72.41 77.41 82.41 87.41 92.41 97.41 102.41 107.41];
            app.Gauge.MinorTicks = [57.41:1:107.41];
            pause(2);
            while (error<=-0.5 || error>=0.5)

                %czytanie dzwieku
                deviceReader = audioDeviceReader;
                Fs=get(deviceReader,'SampleRate');
                Time=0.5;
                dt=1/Fs;
                dN=floor(Time/dt);
                dN=2^(nextpow2(floor(Fs*Time)));
                tvec=dt:dt:10*Time;

                tvec=(1:10*dN)*dt;

                J=length(tvec);
                df=Fs/J;
                f=[0:J-1]*df;
                yvec=zeros(size(tvec));
                N=floor(length(tvec)/10);
                set(deviceReader,'SamplesPerFrame',2^(nextpow2(floor(Fs*Time))));
                set(deviceReader,'NumChannels',1);
                set(deviceReader,'SampleRate',Fs);
                setup(deviceReader)
                memory=[];
                k=0;
                %figure(1)
                while (k<2)
                    % fprintf('nasluchiwanie\n')
                    k=k+1;
                    tic;
                    while (toc < Time)
                        acquiredAudio = deviceReader();
                    end
                    memory=[memory acquiredAudio.'];
                    yvec(1:9*dN)=yvec(dN+1:10*dN);
                    yvec(9*dN+1:10*dN)=acquiredAudio(:);
                    %plot(tvec,yvec)
                    %xlabel('Time [s]')
                    %xlim([tvec(1) tvec(end)])
                    %drawnow
                end
                release(deviceReader)
                %koniec zczytywania


                %filtr
                B1=fir1(4500,[0.001360544218 0.005442176871],'bandpass');
                dzwiek_po_filtracji=filter(B1,1,yvec);
                A1=abs(fft(dzwiek_po_filtracji));
                A2 = A1;
                ind = A1 > 15;
                A1(~ind) = 0;
                %odczytanie czestotliwosci
                for i=1:length(A1)
                    if (floor(A1(i)) == floor(max(A1)))
                        czest_odczyt= f(i)
                        break
                    end
                end


                % porownanie czestotliwosci
                czest_zadana = 82.41;
                error = czest_zadana - czest_odczyt;
                if ( error> 0.5)
                    app.TextArea.Value = ('Zwiększ naciąg struny');
                    app.Gauge.Value = czest_odczyt;
                else
                    if ( error< -0.5)
                        app.TextArea.Value = ('Zmniejsz naciąg struny');
                        app.Gauge.Value = czest_odczyt;
                    end
                end

                pause(1);
            end
            app.Gauge.Value = czest_zadana;
            app.TextArea.Value = ('Nastroiłeś strunę E! Jeśli chcesz nastroić kolejną strunę wybierz ją klikając odpowiedni przycisk');

        end

        % Button pushed function: StrunaAButton
        function StrunaAButtonPushed(app, event)
            error= -10;
            app.Gauge.Limits = [85 135];
            app.Gauge.MajorTicks = [85:5:135];
            app.Gauge.MinorTicks = [85:1:135];
            pause(2);
            while (error<=-0.5 || error>=0.5)

                %czytanie dzwieku
                deviceReader = audioDeviceReader;
                Fs=get(deviceReader,'SampleRate');
                Time=0.5;
                dt=1/Fs;
                dN=floor(Time/dt);
                dN=2^(nextpow2(floor(Fs*Time)));
                tvec=dt:dt:10*Time;

                tvec=(1:10*dN)*dt;

                J=length(tvec);
                df=Fs/J;
                f=[0:J-1]*df;
                yvec=zeros(size(tvec));
                N=floor(length(tvec)/10);
                set(deviceReader,'SamplesPerFrame',2^(nextpow2(floor(Fs*Time))));
                set(deviceReader,'NumChannels',1);
                set(deviceReader,'SampleRate',Fs);
                setup(deviceReader)
                memory=[];
                k=0;
                %figure(1)
                while (k<2)
                    % fprintf('nasluchiwanie\n')
                    k=k+1;
                    tic;
                    while (toc < Time)
                        acquiredAudio = deviceReader();
                    end
                    memory=[memory acquiredAudio.'];
                    yvec(1:9*dN)=yvec(dN+1:10*dN);
                    yvec(9*dN+1:10*dN)=acquiredAudio(:);
                    %plot(tvec,yvec)
                    %xlabel('Time [s]')
                    %xlim([tvec(1) tvec(end)])
                    %drawnow
                end
                release(deviceReader)
                %koniec zczytywania


                %filtr
                B1=fir1(4500,[0.002721088435 0.006802721088],'bandpass');
                dzwiek_po_filtracji=filter(B1,1,yvec);
                A1=abs(fft(dzwiek_po_filtracji));

                ind = A1 > 20;
                A1(~ind) = 0;
                %odczytanie czestotliwosci
                for i=1:length(A1)
                    if (floor(A1(i)) == floor(max(A1)))
                        czest_odczyt= f(i)
                        break
                    end
                end


                % porownanie czestotliwosci
                czest_zadana = 110;
                error = czest_zadana - czest_odczyt;
                if ( error> 0.5)
                    app.TextArea.Value = ('Zwiększ naciąg struny');
                    app.Gauge.Value = czest_odczyt;
                else
                    if ( error< -0.5)
                        app.TextArea.Value = ('Zmniejsz naciąg struny');
                        app.Gauge.Value = czest_odczyt;
                    end
                end

                pause(1);
            end
            app.Gauge.Value = czest_zadana;
            app.TextArea.Value = ('Nastroiłeś strunę A! Jeśli chcesz nastroić kolejną strunę wybierz ją klikając odpowiedni przycisk.');
        end

        % Button pushed function: StrunaDButton
        function StrunaDButtonPushed(app, event)
            error= -10;
            app.Gauge.Limits = [96.83 196.83];
            app.Gauge.MajorTicks = [96.83:10:196.83];
            app.Gauge.MinorTicks = [96.83:2:196.83];
            pause(2);
            while (error<=-1 || error>=1)

                %czytanie dzwieku
                deviceReader = audioDeviceReader;
                Fs=get(deviceReader,'SampleRate');
                Time=0.5;
                dt=1/Fs;
                dN=floor(Time/dt);
                dN=2^(nextpow2(floor(Fs*Time)));
                tvec=dt:dt:10*Time;

                tvec=(1:10*dN)*dt;

                J=length(tvec);
                df=Fs/J;
                f=[0:J-1]*df;
                yvec=zeros(size(tvec));
                N=floor(length(tvec)/10);
                set(deviceReader,'SamplesPerFrame',2^(nextpow2(floor(Fs*Time))));
                set(deviceReader,'NumChannels',1);
                set(deviceReader,'SampleRate',Fs);
                setup(deviceReader)
                memory=[];
                k=0;
                %figure(1)
                while (k<2)
                    % fprintf('nasluchiwanie\n')
                    k=k+1;
                    tic;
                    while (toc < Time)
                        acquiredAudio = deviceReader();
                    end
                    memory=[memory acquiredAudio.'];
                    yvec(1:9*dN)=yvec(dN+1:10*dN);
                    yvec(9*dN+1:10*dN)=acquiredAudio(:);
                    %plot(tvec,yvec)
                    %xlabel('Time [s]')
                    %xlim([tvec(1) tvec(end)])
                    %drawnow
                end
                release(deviceReader)
                %koniec zczytywania


                %filtr
                B1=fir1(4500,[0.004988662132 0.008163265306],'bandpass');
                dzwiek_po_filtracji=filter(B1,1,yvec);
                A1=abs(fft(dzwiek_po_filtracji));

                ind = A1 > 40;
                A1(~ind) = 0;
                %odczytanie czestotliwosci
                for i=1:length(A1)
                    if (floor(A1(i)) == floor(max(A1)))
                        czest_odczyt= f(i)
                        break
                    end
                end


                % porownanie czestotliwosci
                czest_zadana = 146.83;
                error = czest_zadana - czest_odczyt;
                if ( error> 0.5)
                    app.TextArea.Value = ('Zwiększ naciąg struny');
                    app.Gauge.Value = czest_odczyt;
                else
                    if ( error< -0.5)
                        app.TextArea.Value = ('Zmniejsz naciąg struny');
                        app.Gauge.Value = czest_odczyt;
                    end
                end

                pause(1);
            end
            app.Gauge.Value = czest_zadana;
            app.TextArea.Value = ('Nastroiłeś strunę D! Jeśli chcesz nastroić kolejną strunę wybierz ją klikając odpowiedni przycisk.');
        end

        % Button pushed function: StrunaGButton
        function StrunaGButtonPushed(app, event)
            error= -10;
            app.Gauge.Limits = [146 246];
            app.Gauge.MajorTicks = [146:10:246];
            app.Gauge.MinorTicks = [146:2:246];
            pause(2);
            while (error<=-0.5 || error>=0.5)

                %czytanie dzwieku
                deviceReader = audioDeviceReader;
                Fs=get(deviceReader,'SampleRate');
                Time=0.5;
                dt=1/Fs;
                dN=floor(Time/dt);
                dN=2^(nextpow2(floor(Fs*Time)));
                tvec=dt:dt:10*Time;

                tvec=(1:10*dN)*dt;

                J=length(tvec);
                df=Fs/J;
                f=[0:J-1]*df;
                yvec=zeros(size(tvec));
                N=floor(length(tvec)/10);
                set(deviceReader,'SamplesPerFrame',2^(nextpow2(floor(Fs*Time))));
                set(deviceReader,'NumChannels',1);
                set(deviceReader,'SampleRate',Fs);
                setup(deviceReader)
                memory=[];
                k=0;
                %figure(1)
                while (k<2)
                    % fprintf('nasluchiwanie\n')
                    k=k+1;
                    tic;
                    while (toc < Time)
                        acquiredAudio = deviceReader();
                    end
                    memory=[memory acquiredAudio.'];
                    yvec(1:9*dN)=yvec(dN+1:10*dN);
                    yvec(9*dN+1:10*dN)=acquiredAudio(:);
                    %plot(tvec,yvec)
                    %xlabel('Time [s]')
                    %xlim([tvec(1) tvec(end)])
                    %drawnow
                end
                release(deviceReader)
                %koniec zczytywania


                %filtr
                B1=fir1(4500,[0.006802721088 0.01088435374],'bandpass');
                dzwiek_po_filtracji=filter(B1,1,yvec);
                A1=abs(fft(dzwiek_po_filtracji));

                ind = A1 > 40;
                A1(~ind) = 0;
                %odczytanie czestotliwosci
                for i=1:length(A1)
                    if (floor(A1(i)) == floor(max(A1)))
                        czest_odczyt= f(i)
                        break
                    end
                end


                % porownanie czestotliwosci
                czest_zadana = 196;
                error = czest_zadana - czest_odczyt;
                if ( error> 0.5)
                    app.TextArea.Value = ('Zwiększ naciąg struny');
                    app.Gauge.Value = czest_odczyt;
                else
                    if ( error< -0.5)
                        app.TextArea.Value = ('Zmniejsz naciąg struny');
                        app.Gauge.Value = czest_odczyt;
                    end
                end

                pause(1);
            end
            app.Gauge.Value = czest_zadana;
            app.TextArea.Value = ('Nastroiłeś strunę G! Jeśli chcesz nastroić kolejną strunę wybierz ją klikając odpowiedni przycisk.');
        end

        % Button pushed function: StrunaBButton
        function StrunaBButtonPushed(app, event)
            error= -10;
            app.Gauge.Limits = [196.94 296.94];
            app.Gauge.MajorTicks = [196.94:10:296.94];
            app.Gauge.MinorTicks = [196.94:2:296.94];
            pause(2);
            while (error<=-0.5 || error>=0.5)

                %czytanie dzwieku
                deviceReader = audioDeviceReader;
                Fs=get(deviceReader,'SampleRate');
                Time=0.5;
                dt=1/Fs;
                dN=floor(Time/dt);
                dN=2^(nextpow2(floor(Fs*Time)));
                tvec=dt:dt:10*Time;

                tvec=(1:10*dN)*dt;

                J=length(tvec);
                df=Fs/J;
                f=[0:J-1]*df;
                yvec=zeros(size(tvec));
                N=floor(length(tvec)/10);
                set(deviceReader,'SamplesPerFrame',2^(nextpow2(floor(Fs*Time))));
                set(deviceReader,'NumChannels',1);
                set(deviceReader,'SampleRate',Fs);
                setup(deviceReader)
                memory=[];
                k=0;
                %figure(1)
                while (k<2)
                    % fprintf('nasluchiwanie\n')
                    k=k+1;
                    tic;
                    while (toc < Time)
                        acquiredAudio = deviceReader();
                    end
                    memory=[memory acquiredAudio.'];
                    yvec(1:9*dN)=yvec(dN+1:10*dN);
                    yvec(9*dN+1:10*dN)=acquiredAudio(:);
                    %plot(tvec,yvec)
                    %xlabel('Time [s]')
                    %xlim([tvec(1) tvec(end)])
                    %drawnow
                end
                release(deviceReader)
                %koniec zczytywania


                %filtr
                B1=fir1(4500,[0.009070294785 0.01360544218],'bandpass');
                dzwiek_po_filtracji=filter(B1,1,yvec);
                A1=abs(fft(dzwiek_po_filtracji));

                ind = A1 > 40;
                A1(~ind) = 0;
                %odczytanie czestotliwosci
                for i=1:length(A1)
                    if (floor(A1(i)) == floor(max(A1)))
                        czest_odczyt= f(i)
                        break
                    end
                end


                % porownanie czestotliwosci
                czest_zadana = 246.94;
                error = czest_zadana - czest_odczyt;
                if ( error> 0.5)
                    app.TextArea.Value = ('Zwiększ naciąg struny');
                    app.Gauge.Value = czest_odczyt;
                else
                    if ( error< -0.5)
                        app.TextArea.Value = ('Zmniejsz naciąg struny');
                        app.Gauge.Value = czest_odczyt;

                    end
                end

                pause(1);
            end
             app.Gauge.Value = czest_zadana;
            app.TextArea.Value = ('Nastroiłeś strunę B! Jeśli chcesz nastroić kolejną strunę wybierz ją klikając odpowiedni przycisk.');

        end

        % Button pushed function: StrunaeButton
        function StrunaeButtonPushed(app, event)
            error= -10;
                app.Gauge.Limits = [279.63 379.63];
                app.Gauge.MajorTicks = [279.63:10:379.63];
                app.Gauge.MinorTicks = [279.63:2:379.63];
                pause(2);
            while (error<=-0.5 || error>=0.5)
                
                %czytanie dzwieku
                deviceReader = audioDeviceReader;
                Fs=get(deviceReader,'SampleRate');
                Time=0.5;
                dt=1/Fs;
                dN=floor(Time/dt);
                dN=2^(nextpow2(floor(Fs*Time)));
                tvec=dt:dt:10*Time;

                tvec=(1:10*dN)*dt;

                J=length(tvec);
                df=Fs/J;
                f=[0:J-1]*df;
                yvec=zeros(size(tvec));
                N=floor(length(tvec)/10);
                set(deviceReader,'SamplesPerFrame',2^(nextpow2(floor(Fs*Time))));
                set(deviceReader,'NumChannels',1);
                set(deviceReader,'SampleRate',Fs);
                setup(deviceReader)
                memory=[];
                k=0;
                %figure(1)
                while (k<2)
                    % fprintf('nasluchiwanie\n')
                    k=k+1;
                    tic;
                    while (toc < Time)
                        acquiredAudio = deviceReader();
                    end
                    memory=[memory acquiredAudio.'];
                    yvec(1:9*dN)=yvec(dN+1:10*dN);
                    yvec(9*dN+1:10*dN)=acquiredAudio(:);
                    %plot(tvec,yvec)
                    %xlabel('Time [s]')
                    %xlim([tvec(1) tvec(end)])
                    %drawnow
                end
                release(deviceReader)
                %koniec zczytywania


                %filtr
                B1=fir1(4500,[0.01133786848 0.01814058957],'bandpass');
                dzwiek_po_filtracji=filter(B1,1,yvec);
                A1=abs(fft(dzwiek_po_filtracji));

                ind = A1 > 10;
                A1(~ind) = 0;
                %odczytanie czestotliwosci
                for i=1:length(A1)
                    if (floor(A1(i)) == floor(max(A1)))
                        czest_odczyt= f(i)
                        break
                    end
                end


                % porownanie czestotliwosci
                czest_zadana = 329.63;
                error = czest_zadana - czest_odczyt;
                if ( error> 0.5)
                    app.TextArea.Value = ('Zwiększ naciąg struny');
                    app.Gauge.Value = czest_odczyt;
                else
                    if ( error< -0.5)
                        app.TextArea.Value = ('Zmniejsz naciąg struny');
                        app.Gauge.Value = czest_odczyt;
                    end
                end

                pause(1);
            end
            app.Gauge.Value = czest_zadana;
            app.TextArea.Value = ('Nastroiłeś strunę e! Jeśli chcesz nastroić kolejną strunę wybierz ją klikając odpowiedni przycisk.');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StrunaEButton = uibutton(app.UIFigure, 'push');
            app.StrunaEButton.ButtonPushedFcn = createCallbackFcn(app, @StrunaEButtonPushed, true);
            app.StrunaEButton.Text = 'Struna E';
            app.StrunaAButton = uibutton(app.UIFigure, 'push');
            app.StrunaAButton.ButtonPushedFcn = createCallbackFcn(app, @StrunaAButtonPushed, true);
            app.StrunaAButton.Text = 'Struna  A';
            app.StrunaDButton = uibutton(app.UIFigure, 'push');
            app.StrunaDButton.ButtonPushedFcn = createCallbackFcn(app, @StrunaDButtonPushed, true);
            app.StrunaDButton.Text = 'Struna D';
            app.StrunaGButton = uibutton(app.UIFigure, 'push');
            app.StrunaGButton.ButtonPushedFcn = createCallbackFcn(app, @StrunaGButtonPushed, true);
            app.StrunaGButton.Text = 'Struna G';
            app.StrunaBButton = uibutton(app.UIFigure, 'push');
            app.StrunaBButton.ButtonPushedFcn = createCallbackFcn(app, @StrunaBButtonPushed, true);
            app.StrunaBButton.Text = 'Struna B';
            app.StrunaeButton = uibutton(app.UIFigure, 'push');
            app.StrunaeButton.ButtonPushedFcn = createCallbackFcn(app, @StrunaeButtonPushed, true);
            app.StrunaeButton.Text = 'Struna e';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Witaj w stroiku do gitary. Wybierz strunę, którą chciałbyś nastroić:'};
            app.CzstotliwoHzLabel = uilabel(app.UIFigure);
            app.CzstotliwoHzLabel.Text = 'Częstotliwość [Hz]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stroik_aplikacja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end